classdef integralT1all < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WeightFunctionsUIFigure    matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        ErrorEditFieldLabel        matlab.ui.control.Label
        ErrorEditField             matlab.ui.control.NumericEditField
        ExperimentParametersPanel  matlab.ui.container.Panel
        FresnelNumberSliderLabel   matlab.ui.control.Label
        FresnelNumberSlider        matlab.ui.control.Slider
        DeltaSliderLabel           matlab.ui.control.Label
        DeltaSlider                matlab.ui.control.Slider
        ApertureSliderLabel        matlab.ui.control.Label
        ApertureSlider             matlab.ui.control.Slider
        FnField                    matlab.ui.control.NumericEditField
        DeltaField                 matlab.ui.control.NumericEditField
        ApertureField              matlab.ui.control.NumericEditField
        ModelPanel                 matlab.ui.container.Panel
        QratioSliderLabel          matlab.ui.control.Label
        QratioSlider               matlab.ui.control.Slider
        HSliderLabel               matlab.ui.control.Label
        HSlider                    matlab.ui.control.Slider
        QField                     matlab.ui.control.NumericEditField
        HField                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = protected)
                   
            H      =1/3;            %Kolmogorov case.
            q      = 0.00866;       % ratio between inner and outer scale   
            Rm     = 2*pi*.1/.005;  % dimentionless aperture radius 
            deltam = .05*2*pi/.005; % dimentionless separation in the target
            q0     = 1;
            Fm     = .0055;
            errorb = .0000001;      % calculating error
            s      = 0.0000001:.01:1;
            %gammas = gammaswave('divergent',app.s); % emiter type.
            
    end
    
    methods (Access = private)
        
        function results = weightint(app)
            [results, ~] = weight_integral_t(2,app.Fm, app.Rm,app.deltam,gammaswave('divergent',app.s), app.errorb, app.H, app.q, app.s);
            [results1, ~] = weight_integral_1(2,app.Fm, app.Rm,app.deltam,gammaswave('divergent',app.s), app.errorb, app.H, app.q, app.s);
            results = results-results1;
             %clear errorc;
        end
        
        function results = weightgzt(app)
            [results, ~] = weight_integral_t(1,app.Fm*app.q^2,app.q*app.Rm,app.q*app.deltam,gammaswave('divergent',app.s),app.errorb,app.H,app.q0,app.s);
            [results1, ~]= weight_integral_1(1,app.Fm*app.q^2,app.q*app.Rm,app.q*app.deltam,gammaswave('divergent',app.s),app.errorb,app.H,app.q0,app.s);
            
            results=app.q^(-2*app.H-1)*(results-results1);
        end
        
        function results = weightokt(app)
            [results, ~] = weight_integral_t(0,app.Fm*app.q^2, app.q*app.Rm, app.q*app.deltam, gammaswave('divergent',app.s), app.errorb, app.H, 0, app.s);
            [results1,~] = weight_integral_1(0,app.Fm*app.q^2, app.q*app.Rm, app.q*app.deltam, gammaswave('divergent',app.s), app.errorb, app.H, 0, app.s);
            
            results=app.q^(-2*app.H-1)*(results-results1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FnField.Value = app.Fm;
            app.DeltaField.Value=app.deltam;
            app.ApertureField.Value=app.Rm;
            app.QField.Value=app.q;
            app.HField.Value=app.H;
            app.UIAxes.XLim = [app.s(1) app.s(end)];
            app.UIAxes.XGrid= 'on';app.UIAxes.YGrid='on';
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: FresnelNumberSlider
        function FresnelNumberSliderValueChanged(app, event)
            app.Fm = app.FresnelNumberSlider.Value;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
            %app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: DeltaSlider
        function DeltaSliderValueChanged(app, event)
            app.deltam = app.DeltaSlider.Value;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: ApertureSlider
        function ApertureSliderValueChanged(app, event)
            app.Rm = app.ApertureSlider.Value;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: QratioSlider
        function QratioSliderValueChanged(app, event)
            app.q = app.QratioSlider.Value;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: HSlider
        function HSliderValueChanged(app, event)
            app.H = app.HSlider.Value;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changing function: FresnelNumberSlider
        function FresnelNumberSliderValueChanging(app, event)
            changingValue = event.Value;
            app.FnField.Value = changingValue;
        end

        % Value changing function: DeltaSlider
        function DeltaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DeltaField.Value=changingValue;
        end

        % Value changing function: ApertureSlider
        function ApertureSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ApertureField.Value=changingValue;
        end

        % Value changing function: QratioSlider
        function QratioSliderValueChanging(app, event)
            app.QField.Value = event.Value;
            
        end

        % Value changing function: HSlider
        function HSliderValueChanging(app, event)
            app.HField.Value = event.Value;
            
        end

        % Value changed function: DeltaField
        function DeltaFieldValueChanged(app, event)
            app.deltam = app.DeltaField.Value;
            app.DeltaSlider.Value=app.deltam;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: FnField
        function FnFieldValueChanged(app, event)
            app.Fm = app.FnField.Value;
            app.FresnelNumberSlider.Value=app.Fm;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: ApertureField
        function ApertureFieldValueChanged(app, event)
            app.Rm= app.ApertureField.Value;
            app.ApertureSlider.Value=app.Rm;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: QField
        function QFieldValueChanged(app, event)
            app.q = app.QField.Value;
            app.QratioSlider.Value=app.q;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end

        % Value changed function: HField
        function HFieldValueChanged(app, event)
            app.H = app.HField.Value;
            app.HSlider.Value=app.H;
            plot(app.UIAxes,app.s,weightint(app),'b-',app.s,weightgzt(app),'g-',app.s,weightokt(app),'r-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WeightFunctionsUIFigure = uifigure('Visible', 'off');
            app.WeightFunctionsUIFigure.Name = 'Weight Functions';
            app.UIAxes = uiaxes(app.WeightFunctionsUIFigure);
            title(app.UIAxes, 'Comparing weight functions, I_T-I_1')
            xlabel(app.UIAxes, '\zeta')
            ylabel(app.UIAxes, 'dimensionless weight')
            app.ErrorEditFieldLabel = uilabel(app.WeightFunctionsUIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.WeightFunctionsUIFigure, 'numeric');
            app.ExperimentParametersPanel = uipanel(app.WeightFunctionsUIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.FresnelNumberSliderLabel = uilabel(app.ExperimentParametersPanel);
            app.FresnelNumberSliderLabel.Text = 'Fresnel Number';
            app.FresnelNumberSlider = uislider(app.ExperimentParametersPanel);
            app.FresnelNumberSlider.Limits = [0 10000];
            app.FresnelNumberSlider.ValueChangedFcn = createCallbackFcn(app, @FresnelNumberSliderValueChanged, true);
            app.FresnelNumberSlider.ValueChangingFcn = createCallbackFcn(app, @FresnelNumberSliderValueChanging, true);
            app.FresnelNumberSlider.Value = 0.0055;
            app.DeltaSliderLabel = uilabel(app.ExperimentParametersPanel);
            app.DeltaSliderLabel.Text = 'Delta';
            app.DeltaSlider = uislider(app.ExperimentParametersPanel);
            app.DeltaSlider.Limits = [0 300];
            app.DeltaSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DeltaSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaSliderValueChanged, true);
            app.DeltaSlider.ValueChangingFcn = createCallbackFcn(app, @DeltaSliderValueChanging, true);
            app.DeltaSlider.Value = 62.8318530718;
            app.ApertureSliderLabel = uilabel(app.ExperimentParametersPanel);
            app.ApertureSliderLabel.Text = 'Aperture';
            app.ApertureSlider = uislider(app.ExperimentParametersPanel);
            app.ApertureSlider.Limits = [0.5 700];
            app.ApertureSlider.ValueChangedFcn = createCallbackFcn(app, @ApertureSliderValueChanged, true);
            app.ApertureSlider.ValueChangingFcn = createCallbackFcn(app, @ApertureSliderValueChanging, true);
            app.ApertureSlider.Value = 125.6637061436;
            app.FnField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.FnField.ValueChangedFcn = createCallbackFcn(app, @FnFieldValueChanged, true);
            app.DeltaField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.DeltaField.ValueChangedFcn = createCallbackFcn(app, @DeltaFieldValueChanged, true);
            app.ApertureField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.ApertureField.ValueChangedFcn = createCallbackFcn(app, @ApertureFieldValueChanged, true);
            app.ModelPanel = uipanel(app.WeightFunctionsUIFigure);
            app.ModelPanel.Title = 'Model';
            app.QratioSliderLabel = uilabel(app.ModelPanel);
            app.QratioSliderLabel.Text = 'Q ratio';
            app.QratioSlider = uislider(app.ModelPanel);
            app.QratioSlider.Limits = [0 1];
            app.QratioSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.QratioSlider.ValueChangedFcn = createCallbackFcn(app, @QratioSliderValueChanged, true);
            app.QratioSlider.ValueChangingFcn = createCallbackFcn(app, @QratioSliderValueChanging, true);
            app.QratioSlider.Value = 0.00866;
            app.HSliderLabel = uilabel(app.ModelPanel);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.ModelPanel);
            app.HSlider.Limits = [0 1];
            app.HSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider.ValueChangingFcn = createCallbackFcn(app, @HSliderValueChanging, true);
            app.HSlider.Value = 0.333333333333333;
            app.QField = uieditfield(app.ModelPanel, 'numeric');
            app.QField.ValueChangedFcn = createCallbackFcn(app, @QFieldValueChanged, true);
            app.HField = uieditfield(app.ModelPanel, 'numeric');
            app.HField.ValueChangedFcn = createCallbackFcn(app, @HFieldValueChanged, true);
            % Show the figure after all components are created
            app.WeightFunctionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integralT1all

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WeightFunctionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WeightFunctionsUIFigure)
        end
    end
end