classdef variances < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VariancesFunctionUIFigure   matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        ExperimentParametersPanel   matlab.ui.container.Panel
        FresnelNumberSliderLabel    matlab.ui.control.Label
        FresnelNumberSlider         matlab.ui.control.Slider
        DeltaSliderLabel            matlab.ui.control.Label
        ApertureSliderLabel         matlab.ui.control.Label
        ApertureSlider              matlab.ui.control.Slider
        FnField                     matlab.ui.control.NumericEditField
        DeltaField                  matlab.ui.control.NumericEditField
        ApertureField               matlab.ui.control.NumericEditField
        InnerScaleEditFieldLabel    matlab.ui.control.Label
        InnerScaleEditField         matlab.ui.control.NumericEditField
        LEditFieldLabel             matlab.ui.control.Label
        LEditField                  matlab.ui.control.NumericEditField
        ModelPanel                  matlab.ui.container.Panel
        QratioSliderLabel           matlab.ui.control.Label
        QratioSlider                matlab.ui.control.Slider
        HSliderLabel                matlab.ui.control.Label
        HSlider                     matlab.ui.control.Slider
        QField                      matlab.ui.control.NumericEditField
        HField                      matlab.ui.control.NumericEditField
        StructureConstantEditFieldLabel  matlab.ui.control.Label
        StructureConstantEditField  matlab.ui.control.EditField
        InitialDeltaEditFieldLabel  matlab.ui.control.Label
        InitialDeltaEditField       matlab.ui.control.NumericEditField
        DeltaStepEditFieldLabel     matlab.ui.control.Label
        DeltaStepEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = protected) % YOUR VARIABLES MUST BE DEFINED HERE
                   
            H           = 1/3;            %Kolmogorov case.
            q           = 0.00866;       % ratio between inner and outer scale   
            iscale      = 0.005; 
            km          = 2*pi/0.005;
            Rm          = 2*pi/0.005*.1;  % dimentionless aperture radius 
            deltam      = .05*2*pi/0.005;   % dimentionless separation in the target
            %q0     = 1;
            Fm          = .0055;
            errorb      = .0000001;      % calculating error
            resolution  = 6;
            L           = 8;
            s           = 0.0000001:6:(.05*2*pi/0.005);
            c2n         = 1E-11;
            deltam0     = 0.0000001
            step        = 10;
            %gammas = gammaswave('divergent',app.s); % emiter type.
            
    end
    
    methods (Access = protected) % YOUR FUNCTIONS MUST BE HERE
        function s = deltams(app)
            s = app.deltam0:(app.deltam-app.deltam0)/app.step:app.deltam;
        end
        function results = varperpint(app)
            
            results = variance_constant(app.H,app.L,app.Rm,2*pi/app.iscale).*integral1T(1,2,app.Fm, app.Rm, app.s, 'divergent', app.errorb, app.H, app.q,app.c2n);
          
        end
         
        function results = varparaint(app)
            results = variance_constant(app.H,app.L,app.Rm,2*pi/app.iscale).*integral1T(2,2,app.Fm, app.Rm, app.s, 'divergent', app.errorb, app.H, app.q,app.c2n);
            
        end
        
        function results = varperpgzt(app)
             results = variance_constant(app.H,app.L,app.Rm,2*pi/app.iscale,app.q).*integral1T(1,1,app.Fm*app.q^2,app.q*app.Rm, app.q*app.s, 'divergent', app.errorb, app.H, app.q,app.c2n);
     end
        
        function results = varparagzt(app)
             results = variance_constant(app.H,app.L,app.Rm,2*pi/app.iscale,app.q).*integral1T(2,1,app.Fm*app.q^2, app.q*app.Rm, app.q*app.s, 'divergent', app.errorb, app.H, app.q,app.c2n);

        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name_c2n)
            app.FnField.Value = app.Fm;
            app.DeltaField.Value=app.deltam;
            app.ApertureField.Value=app.Rm;
            app.QField.Value=app.q;
            app.HField.Value=app.H;
            app.LEditField.Value=app.L;
            app.InitialDeltaEditField.Value=app.deltam0;
            app.DeltaStepEditField.Value=app.step;
            app.InnerScaleEditField.Value=app.iscale;
            if nargin >=2
               app.c2n = evalin("base",name_c2n);
               app.StructureConstantEditField.Value = name_c2n;
            end
            app.s = deltams(app);
            
            app.UIAxes.XLim = [app.s(1) app.s(end)];
            app.UIAxes.XGrid= 'on';app.UIAxes.YGrid='on';
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: FresnelNumberSlider
        function FresnelNumberSliderValueChanged(app, event)
            app.Fm = app.FresnelNumberSlider.Value;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
            %app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: ApertureSlider
        function ApertureSliderValueChanged(app, event)
            app.Rm = app.ApertureSlider.Value;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: QratioSlider
        function QratioSliderValueChanged(app, event)
            app.q = app.QratioSlider.Value;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: HSlider
        function HSliderValueChanged(app, event)
            app.H = app.HSlider.Value;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changing function: FresnelNumberSlider
        function FresnelNumberSliderValueChanging(app, event)
            changingValue = event.Value;
            app.FnField.Value = changingValue;
        end

        % Value changing function: ApertureSlider
        function ApertureSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ApertureField.Value=changingValue;
        end

        % Value changing function: QratioSlider
        function QratioSliderValueChanging(app, event)
            app.QField.Value = event.Value;
            
        end

        % Value changing function: HSlider
        function HSliderValueChanging(app, event)
            app.HField.Value = event.Value;
            
        end

        % Value changed function: DeltaField
        function DeltaFieldValueChanged(app, event)
            app.deltam = app.DeltaField.Value;
            %app.DeltaSlider.Value=app.deltam;
            app.s = deltams(app);
            app.UIAxes.XLim = [app.s(1) app.s(end)];
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: FnField
        function FnFieldValueChanged(app, event)
            app.Fm = app.FnField.Value;
            app.FresnelNumberSlider.Value=app.Fm;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: ApertureField
        function ApertureFieldValueChanged(app, event)
            app.Rm= app.ApertureField.Value;
            app.ApertureSlider.Value=app.Rm;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: QField
        function QFieldValueChanged(app, event)
            app.q = app.QField.Value;
            app.QratioSlider.Value=app.q;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: HField
        function HFieldValueChanged(app, event)
            app.H = app.HField.Value;
            app.HSlider.Value=app.H;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.L = app.LEditField.Value;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: InnerScaleEditField
        function InnerScaleEditFieldValueChanged(app, event)
            app.iscale = app.InnerScaleEditField.Value;
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        end

        % Value changed function: InitialDeltaEditField
        function InitialDeltaEditFieldValueChanged(app, event)
            app.deltam0 = app.InitialDeltaEditField.Value;
            %app.deltam = app.DeltaField.Value;
            
            app.s = deltams(app);
            app.UIAxes.XLim = [app.s(1) app.s(end)];
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
            
        end

        % Value changed function: DeltaStepEditField
        function DeltaStepEditFieldValueChanged(app, event)
            app.step = app.DeltaStepEditField.Value;
            app.s = deltams(app);
            app.UIAxes.XLim = [app.s(1) app.s(end)];
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        
        end

        % Value changed function: StructureConstantEditField
        function StructureConstantEditFieldValueChanged(app, event)
            app.c2n = evalin("base", app.StructureConstantEditField.Value);
            plot(app.UIAxes,app.s,varperpint(app),'b-',app.s,varparaint(app),'r-',app.s,varperpgzt(app),'k-',app.s,varparagzt(app),'c-');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VariancesFunctionUIFigure = uifigure('Visible', 'off');
            app.VariancesFunctionUIFigure.Name = 'Variances';
            app.UIAxes = uiaxes(app.VariancesFunctionUIFigure);
            title(app.UIAxes, 'Comparing Variances')
            xlabel(app.UIAxes, '\delta_m')
            ylabel(app.UIAxes, 'Variances')
            app.ExperimentParametersPanel = uipanel(app.VariancesFunctionUIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.FresnelNumberSliderLabel = uilabel(app.ExperimentParametersPanel);
            app.FresnelNumberSliderLabel.Text = 'Fresnel Number';
            app.FresnelNumberSlider = uislider(app.ExperimentParametersPanel);
            app.FresnelNumberSlider.Limits = [0 10000];
            app.FresnelNumberSlider.ValueChangedFcn = createCallbackFcn(app, @FresnelNumberSliderValueChanged, true);
            app.FresnelNumberSlider.ValueChangingFcn = createCallbackFcn(app, @FresnelNumberSliderValueChanging, true);
            app.FresnelNumberSlider.Value = 0.0055;
            app.DeltaSliderLabel = uilabel(app.ExperimentParametersPanel);
            app.DeltaSliderLabel.Text = 'Final Delta';
            app.ApertureSliderLabel = uilabel(app.ExperimentParametersPanel);
            app.ApertureSliderLabel.Text = 'Aperture';
            app.ApertureSlider = uislider(app.ExperimentParametersPanel);
            app.ApertureSlider.Limits = [0.5 700];
            app.ApertureSlider.ValueChangedFcn = createCallbackFcn(app, @ApertureSliderValueChanged, true);
            app.ApertureSlider.ValueChangingFcn = createCallbackFcn(app, @ApertureSliderValueChanging, true);
            app.ApertureSlider.Value = 125.6637061436;
            app.FnField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.FnField.ValueChangedFcn = createCallbackFcn(app, @FnFieldValueChanged, true);
            app.DeltaField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.DeltaField.ValueChangedFcn = createCallbackFcn(app, @DeltaFieldValueChanged, true);
            app.ApertureField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.ApertureField.ValueChangedFcn = createCallbackFcn(app, @ApertureFieldValueChanged, true);
            app.InnerScaleEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.InnerScaleEditFieldLabel.Text = 'Inner Scale';
            app.InnerScaleEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.InnerScaleEditField.ValueChangedFcn = createCallbackFcn(app, @InnerScaleEditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.ModelPanel = uipanel(app.ExperimentParametersPanel);
            app.ModelPanel.Title = 'Model';
            app.QratioSliderLabel = uilabel(app.ModelPanel);
            app.QratioSliderLabel.Text = 'Q ratio';
            app.QratioSlider = uislider(app.ModelPanel);
            app.QratioSlider.Limits = [0 1];
            app.QratioSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.QratioSlider.ValueChangedFcn = createCallbackFcn(app, @QratioSliderValueChanged, true);
            app.QratioSlider.ValueChangingFcn = createCallbackFcn(app, @QratioSliderValueChanging, true);
            app.QratioSlider.Value = 0.00866;
            app.HSliderLabel = uilabel(app.ModelPanel);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.ModelPanel);
            app.HSlider.Limits = [0 1];
            app.HSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider.ValueChangingFcn = createCallbackFcn(app, @HSliderValueChanging, true);
            app.HSlider.Value = 0.333333333333333;
            app.QField = uieditfield(app.ModelPanel, 'numeric');
            app.QField.ValueChangedFcn = createCallbackFcn(app, @QFieldValueChanged, true);
            app.HField = uieditfield(app.ModelPanel, 'numeric');
            app.HField.ValueChangedFcn = createCallbackFcn(app, @HFieldValueChanged, true);
            app.StructureConstantEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.StructureConstantEditFieldLabel.Text = 'Structure Constant';
            app.StructureConstantEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.StructureConstantEditField.ValueChangedFcn = createCallbackFcn(app, @StructureConstantEditFieldValueChanged, true);
            app.InitialDeltaEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.InitialDeltaEditFieldLabel.Text = 'Initial Delta';
            app.InitialDeltaEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.InitialDeltaEditField.ValueChangedFcn = createCallbackFcn(app, @InitialDeltaEditFieldValueChanged, true);
            app.DeltaStepEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.DeltaStepEditFieldLabel.Text = 'Delta Step';
            app.DeltaStepEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.DeltaStepEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaStepEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.VariancesFunctionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = variances(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VariancesFunctionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VariancesFunctionUIFigure)
        end
    end
end